classdef CalculosApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        VolverButton              matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        GraficarCategoriasButton  matlab.ui.control.Button
        TopCarosButton            matlab.ui.control.Button
        ValorInventarioButton     matlab.ui.control.Button
        StockBajoButton           matlab.ui.control.Button
        TotalProductoButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
       conn % Conexión a la base de datos
       umbralStockBajo = 10; % Valor por defecto
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CalcularButtonPushed(app, event)
           conn = conectarPostgres(17);
    data = fetch(conn, 'SELECT * FROM articulos');
    close(conn);

    % Calculos
    total = height(data);
    edadProm = mean(data.edad);
    porcentajeDiabetes = 100 * sum(data.tiene_diabetes) / total;
    porcentajeHipertension = 100 * sum(data.tiene_hipertension) / total;

    % Mostrar
    app.NumPersonasEditField.Value = total;
    app.EdadPromedioEditField.Value = round(edadProm, 2);
    app.DiabetesEditField.Value = sprintf('%.1f%%', porcentajeDiabetes);
    app.HipertensionEditField.Value = sprintf('%.1f%%', porcentajeHipertension);
        end

        % Button pushed function: TotalProductoButton
        function TotalProductoButtonPushed(app, event)
            
    try
        % Establecer conexión si no existe
        if isempty(app.conn) || ~isvalid(app.conn)
            app.conn = conectarPostgres(); 
        end
        
        % Consulta SQL para calcular totales
        query = ['SELECT '...
                'COUNT(*) AS total_productos, '...
                'SUM(cantidad) AS total_unidades, '...
                'SUM(precio * cantidad) AS valor_total '...
                'FROM articulos'];
        
        % Ejecutar consulta
        data = fetch(app.conn, query);
        
        % Mostrar resultados
        if ~isempty(data)
            % Crear mensaje con los resultados
            mensaje = sprintf([...
                '📊 Resumen de Inventario:\n\n'...
                '• Total de productos diferentes: %d\n'...
                '• Total de unidades en stock: %d\n'...
                '• Valor total del inventario: $%.2f'], ...
                data.total_productos, ...
                data.total_unidades, ...
                data.valor_total);
            
            % Mostrar en UIAlert
            uialert(app.UIFigure, mensaje, 'Totales', 'Icon', 'info');
            
            % Opcional: Mostrar también en un campo de texto
            if isprop(app, 'ResultadosLabel')
                app.ResultadosLabel.Text = mensaje;
            end
        else
            uialert(app.UIFigure, 'No se encontraron datos', 'Advertencia');
        end
        
    catch ME
        uialert(app.UIFigure, ['Error: ' ME.message], 'Error', 'Icon', 'error');
        disp(getReport(ME));
    end
        end

        % Button pushed function: StockBajoButton
        function StockBajoButtonPushed(app, event)
            try
        % Establecer conexión si no existe
        if isempty(app.conn) || ~isvalid(app.conn)
            app.conn = conectarPostgres(); 
        end
        
        % Definir umbral de stock bajo (puedes hacerlo configurable)
        umbralStockBajo = 10; % Productos con menos de 10 unidades
        
        % Consulta SQL para encontrar productos con stock bajo
        query = sprintf(['SELECT codigo, nombre, cantidad, precio '...
                        'FROM articulos '...
                        'WHERE cantidad < %d '...
                        'ORDER BY cantidad ASC'], umbralStockBajo);
        
        % Ejecutar consulta
        data = fetch(app.conn, query);
        
        % Mostrar resultados
        if ~isempty(data)
            % Crear mensaje con los resultados
            if istable(data)
                % Formatear resultados como tabla
                resultStr = sprintf('📉 Productos con stock bajo (<%d unidades):\n\n', umbralStockBajo);
                
                % Construir tabla de resultados
                for i = 1:min(height(data), 20) % Mostrar máximo 20 productos
                    resultStr = sprintf('%s• %s (%s) - %d unidades - $%.2f\n', ...
                                      resultStr, ...
                                      data.nombre{i}, ...
                                      data.codigo{i}, ...
                                      data.cantidad(i), ...
                                      data.precio(i));
                end
                
                % Mostrar total
                resultStr = sprintf('%s\nTotal productos con stock bajo: %d', ...
                                   resultStr, height(data));
            else
                resultStr = 'Formato de datos no reconocido';
            end
            
            % Mostrar en UIAlert
            uialert(app.UIFigure, resultStr, 'Stock Bajo', 'Icon', 'warning');
            
            % Opcional: Mostrar en una tabla UI
            if isprop(app, 'TablaResultados')
                app.TablaResultados.Data = data;
            end
        else
            uialert(app.UIFigure, sprintf('✅ No hay productos con stock bajo (<%d unidades)', umbralStockBajo), ...
                   'Stock Bajo', 'Icon', 'success');
        end
        
    catch ME
        errorMsg = sprintf('Error al calcular stock bajo:\n%s\n\nDetalles:\n%s', ...
                         ME.message, getReport(ME, 'basic'));
        uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
    end
        end

        % Button pushed function: ValorInventarioButton
        function ValorInventarioButtonPushed(app, event)
           try
        % Establecer conexión si no existe
        if isempty(app.conn) || ~isvalid(app.conn)
            app.conn = conectarPostgres(); 
        end
        
        % Consulta SQL optimizada para cálculo de valor total
        query = ['SELECT '...
                'COUNT(*) AS total_productos, '...
                'SUM(cantidad) AS total_unidades, '...
                'ROUND(SUM(precio * cantidad), 2) AS valor_total, '...
                'ROUND(AVG(precio), 2) AS precio_promedio '...
                'FROM articulos'];
        
        % Ejecutar consulta
        data = fetch(app.conn, query);
        
        % Mostrar resultados
        if ~isempty(data)
            % Formatear mensaje con los resultados
            msg = sprintf(['📊 Valor Total del Inventario\n\n'...
                         '• Productos diferentes: %d\n'...
                         '• Unidades totales: %d\n'...
                         '• Precio promedio por unidad: $%.2f\n'...
                         '• VALOR TOTAL DEL INVENTARIO: $%.2f'], ...
                         data.total_productos, ...
                         data.total_unidades, ...
                         data.precio_promedio, ...
                         data.valor_total);
            
            % Mostrar en UIAlert con formato mejorado
            uialert(app.UIFigure, msg, 'Valor del Inventario', ...
                   'Icon', 'success', ...
                   'Interpreter', 'tex'); % Permite formato básico
            
            % Opcional: Mostrar también en un campo de texto
            if isprop(app, 'ResultadoValorTotalLabel')
                app.ResultadoValorTotalLabel.Text = msg;
            end
            
            % Opcional: Registrar en un archivo
            registrarValorInventario(data.valor_total);
        else
            uialert(app.UIFigure, 'No se encontraron datos en el inventario', ...
                   'Advertencia', 'Icon', 'warning');
        end
        
    catch ME
        errorMsg = sprintf('❌ Error al calcular valor:\n%s\n\nDetalles técnicos:\n%s', ...
                         ME.message, getReport(ME, 'basic'));
        uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
    end 
        end

        % Button pushed function: TopCarosButton
        function TopCarosButtonPushed(app, event)
             try
        % 1. Establecer conexión
        if isempty(app.conn) || ~isvalid(app.conn)
            app.conn = conectarPostgres(); 
        end
        
        % 2. Consulta para obtener los artículos más caros
        query = ['SELECT codigo, nombre, precio, cantidad, '...
                'ROUND(precio * cantidad, 2) AS valor_total '...
                'FROM articulos '...
                'ORDER BY precio DESC '...
                'LIMIT 10'];  % Top 10 artículos más caros
        
        % 3. Ejecutar consulta
        data = fetch(app.conn, query);
        
        % 4. Verificar datos
        if isempty(data)
            uialert(app.UIFigure, 'No se encontraron artículos en el inventario', ...
                   'Advertencia', 'Icon', 'warning');
            return;
        end
        
        % 5. Crear y configurar una nueva figura
        fig = figure('Name', 'Artículos Más Caros', 'NumberTitle', 'off', ...
                    'Position', [100 100 800 500], 'Color', 'w');
        
        % 6. Crear tabla UI para mostrar los datos
        uit = uitable(fig, 'Data', table2cell(data), ...
                     'ColumnName', {'Código', 'Nombre', 'Precio Unitario', 'Cantidad', 'Valor Total'}, ...
                     'RowName', [], ...
                     'Units', 'normalized', ...
                     'Position', [0.05 0.05 0.9 0.85]);
        
        % 7. Formatear columnas
        format = {'char', 'char', 'bank', 'numeric', 'bank'};
        for i = 1:length(format)
            uit.ColumnFormat{i} = format{i};
        end
        
        % 8. Ajustar ancho de columnas
        uit.ColumnWidth = {80, 200, 100, 80, 100};
        
        % 9. Añadir título
        uicontrol(fig, 'Style', 'text', ...
                 'String', 'TOP 10 ARTÍCULOS MÁS CAROS', ...
                 'Units', 'normalized', ...
                 'Position', [0.05 0.92 0.9 0.06], ...
                 'FontSize', 14, 'FontWeight', 'bold', ...
                 'BackgroundColor', 'white');
        
        % 10. Opcional: Añadir gráfico de barras
        subplot('Position', [0.05 0.05 0.4 0.4]);
        barh(data.precio(end:-1:1), 'FaceColor', [0.8 0.2 0.2]);
        set(gca, 'YTick', 1:height(data), 'YTickLabel', data.nombre(end:-1:1));
        title('Precio Unitario');
        xlabel('Precio ($)');
        grid on;
        
    catch ME
        errorMsg = ['❌ Error al obtener artículos más caros:' newline...
                   ME.message newline newline...
                   'Consulta ejecutada:' newline...
                   query];
                   
        uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
        
        if exist('fig', 'var') && ishandle(fig)
            close(fig);
        end
    end
        end

        % Button pushed function: GraficarCategoriasButton
        function GraficarCategoriasButtonPushed(app, event)
          try
        % 1. Establecer conexión
        if isempty(app.conn) || ~isvalid(app.conn)
            app.conn = conectarPostgres(); 
        end
        
        % 2. Consulta SQL corregida (sin JOIN ni referencia a tabla categorias)
        query = ['SELECT id_categoria, '...
                'COUNT(*) AS cantidad_productos, '...
                'SUM(cantidad) AS total_unidades, '...
                'ROUND(SUM(precio * cantidad), 2) AS valor_total '...
                'FROM articulos '...
                'GROUP BY id_categoria '...
                'ORDER BY valor_total DESC'];
        
        % 3. Ejecutar consulta
        data = fetch(app.conn, query);
        
        % 4. Verificar datos
        if isempty(data)
            uialert(app.UIFigure, 'No se encontraron datos para graficar', ...
                   'Advertencia', 'Icon', 'warning');
            return;
        end
        
        % 5. Crear gráficos
        fig = figure('Name', 'Análisis por Categorías', 'NumberTitle', 'off', ...
                    'Position', [100 100 900 600], 'Color', 'w');
        
        % Convertir IDs de categoría a texto para las etiquetas
        categorias = arrayfun(@(x) sprintf('Cat. %d', x), data.id_categoria, 'UniformOutput', false);
        
        % Gráfico 1: Valor total por categoría (Barras)
        subplot(2,2,1);
        bar(categorical(categorias), data.valor_total, 'FaceColor', [0.2 0.6 0.8]);
        title('Valor Total por Categoría', 'FontSize', 12, 'FontWeight', 'bold');
        xlabel('ID Categoría', 'FontSize', 10);
        ylabel('Valor ($)', 'FontSize', 10);
        grid on;
        xtickangle(45);
        
        % Gráfico 2: Distribución porcentual (Torta)
        subplot(2,2,2);
        pie(data.valor_total, categorias);
        title('Distribución del Valor', 'FontSize', 12, 'FontWeight', 'bold');
        
        % Gráfico 3: Cantidad de productos (Barras horizontales)
        subplot(2,2,3);
        barh(categorical(categorias), data.cantidad_productos, 'FaceColor', [0.4 0.8 0.6]);
        title('Productos por Categoría', 'FontSize', 12, 'FontWeight', 'bold');
        xlabel('Cantidad de Productos', 'FontSize', 10);
        grid on;
        
        % Gráfico 4: Unidades totales (Gráfico de área)
        subplot(2,2,4);
        area(categorical(categorias), data.total_unidades, 'FaceColor', [0.8 0.4 0.4]);
        title('Unidades por Categoría', 'FontSize', 12, 'FontWeight', 'bold');
        xlabel('ID Categoría', 'FontSize', 10);
        ylabel('Unidades', 'FontSize', 10);
        grid on;
        xtickangle(45);
        
        % Título general
        sgtitle('Análisis por ID de Categoría', 'FontSize', 14, 'FontWeight', 'bold');
        
    catch ME
        errorMsg = ['❌ Error al generar gráficos:' newline...
                   ME.message newline newline...
                   'Consulta ejecutada:' newline...
                   query newline newline...
                   'Error confirmado: Solo se usa la columna id_categoria de articulos'];
                   
        uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
        
        if exist('fig', 'var') && ishandle(fig)
            close(fig);
        end
    end
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
        '¿desea volver al menu?', ...  % Mensaje
        'Confirmar salida', ...                                 % Título
        'Options', {'Sí', 'No'}, ...                            % Botones
        'DefaultOption', 2, ...                                 % Opción por defecto: No
        'CancelOption', 2);  
         if strcmp(selection, 'Sí')
            MainMenuApp;
            delete(app);
         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TotalProductoButton = uibutton(app.UIFigure, 'push');
            app.TotalProductoButton.ButtonPushedFcn = createCallbackFcn(app, @TotalProductoButtonPushed, true);
            app.TotalProductoButton.Text = 'TotalProducto';
            app.StockBajoButton = uibutton(app.UIFigure, 'push');
            app.StockBajoButton.ButtonPushedFcn = createCallbackFcn(app, @StockBajoButtonPushed, true);
            app.StockBajoButton.Text = 'StockBajo';
            app.ValorInventarioButton = uibutton(app.UIFigure, 'push');
            app.ValorInventarioButton.ButtonPushedFcn = createCallbackFcn(app, @ValorInventarioButtonPushed, true);
            app.ValorInventarioButton.Text = 'ValorInventario';
            app.TopCarosButton = uibutton(app.UIFigure, 'push');
            app.TopCarosButton.ButtonPushedFcn = createCallbackFcn(app, @TopCarosButtonPushed, true);
            app.TopCarosButton.Text = 'TopCaros';
            app.GraficarCategoriasButton = uibutton(app.UIFigure, 'push');
            app.GraficarCategoriasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarCategoriasButtonPushed, true);
            app.GraficarCategoriasButton.Text = 'Graficar-Categorias';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalculosApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end