classdef DatosApp_LISTO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        VovelButton      matlab.ui.control.Button
        RefrescarButton  matlab.ui.control.Button
        ExportarButton   matlab.ui.control.Button
        buscarButton     matlab.ui.control.Button
        EliminarButton   matlab.ui.control.Button
        AgregarButton    matlab.ui.control.Button
        CargarButton     matlab.ui.control.Button
        DatosTable       matlab.ui.control.Table
    end

    
    properties (Access = public)
        BuscarEditField  matlab.ui.control.EditField % Campo para ingresar texto de búsqueda
    end
    
    properties (Access = private)
        conn % Description
        currentData % Para almacenar los datos actuales de la tabla
    end
    
    methods (Access = public)
        
        % Función para actualizar la tabla (añade esto)
    function actualizarTabla(app)
        try
        % Verificar conexión primero
        if isempty(app.conn) || ~isvalid(app.conn) || isempty(app.conn.Message)
            app.conn = conectarPostgres();
        end
        
        % Consulta con manejo de errores
        curs = exec(app.conn, 'SELECT * FROM articulos ORDER BY codigo LIMIT 100');
        curs = fetch(curs);
        
        if ~isempty(curs.Data)
            app.DatosTable.Data = curs.Data;
        else
            uialert(app.UIFigure, 'No se encontraron datos', 'Información');
        end
    catch ME
        uialert(app.UIFigure, ['Error al actualizar: ' ME.message], 'Error', 'Icon', 'error');
    end
    end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            app.conn = conectarPostgres();
    
    % Consulta SQL
    data = fetch(app.conn, 'SELECT * FROM articulos');
    
    % Mostrar en la tabla
    app.DatosTable.Data = data;

    % Cerrar conexión
    close(app.conn);

        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
           % Crear diálogo para ingresar datos del nuevo producto
    prompt = {
        'Código (ej: A011):', 
        'Nombre del producto:', 
        'ID Categoría (1-5):', 
        'Precio (ej: 100.00):', 
        'Cantidad:', 
        'ID Proveedor (1-5):'
    };
    
    dlgtitle = 'Nuevo Producto';
    dims = [1 50]; % Tamaño de los campos de entrada
    definput = {'', '', '1', '0.00', '0', '1'}; % Valores por defecto
    
    % Mostrar diálogo de entrada
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    
    % Si el usuario presionó OK (no Cancel)
    if ~isempty(answer)
        try
            % Validar campos obligatorios
            if isempty(answer{1}) || isempty(answer{2})
                error('Código y Nombre son campos obligatorios');
            end
            
            % Verificar/establecer conexión (versión corregida)
            if isempty(app.conn) || ~isvalid(app.conn) || isempty(app.conn.Message)
                app.conn = conectarPostgres();
            else
                try
                    % Prueba simple para verificar si la conexión está activa
                    curs = exec(app.conn, 'SELECT 1');
                    close(curs);
                catch
                    app.conn = conectarPostgres(); % Reconectar si falla
                end
            end
            
            % Construir consulta SQL segura
            valores = cellfun(@(x) strrep(x, "'", "''"), answer, 'UniformOutput', false);
            
            % Convertir y validar números
            id_categoria = str2double(valores{3});
            precio = str2double(valores{4});
            cantidad = str2double(valores{5});
            id_proveedor = str2double(valores{6});
            
            if any(isnan([id_categoria, precio, cantidad, id_proveedor]))
                error('Los campos numéricos deben contener valores válidos');
            end
            
            % Crear consulta INSERT con parámetros
            query = sprintf(['INSERT INTO articulos (codigo, nombre, id_categoria, ', ...
                           'precio, cantidad, id_proveedor) VALUES (', ...
                           '''%s'', ''%s'', %d, %.2f, %d, %d)'], ...
                           valores{1}, valores{2}, id_categoria, precio, cantidad, id_proveedor);
            
            % Ejecutar consulta
            exec(app.conn, query);
            
            % Confirmar y actualizar tabla
            uialert(app.UIFigure, 'Producto agregado correctamente', 'Éxito', 'Icon', 'success');
            actualizarTabla(app);
            
        catch ME
            uialert(app.UIFigure, ['Error al agregar producto: ' ME.message], 'Error', 'Icon', 'error');
            disp(getReport(ME, 'extended'));
        end
    end
    
        end

        % Button pushed function: buscarButton
        function buscarButtonPushed(app, event)
             try
        % Obtener el texto de búsqueda
        textoBusqueda = app.BuscarEditField.Value;
        
        % Validar que se haya ingresado texto
        if isempty(textoBusqueda)
            uialert(app.UIFigure, 'Por favor ingrese un término de búsqueda', 'Campo vacío', 'Icon', 'warning');
            return;
        end
        
        % Establecer conexión si no existe
        if isempty(app.conn) || ~isconnection(app.conn)
            app.conn = conectarPostgres();
        end
        
        % Construir consulta SQL segura para tu estructura de base de datos
        searchTerm = strrep(textoBusqueda, "'", "''"); % Escapar comillas simples
        
        query = ['SELECT * FROM articulos WHERE '...
                'codigo LIKE ''%' searchTerm '%'' OR '...
                'LOWER(nombre) LIKE ''%' lower(searchTerm) '%'' OR '...
                'CAST(precio AS TEXT) LIKE ''%' searchTerm '%'' OR '...
                'CAST(cantidad AS TEXT) LIKE ''%' searchTerm '%'' '...
                'ORDER BY codigo LIMIT 100'];
        
        % Ejecutar consulta (método compatible con tu versión de MATLAB)
        curs = exec(app.conn, query);
        curs = fetch(curs);
        data = curs.Data;
        
        % Mostrar resultados
        if isempty(data)
            uialert(app.UIFigure, 'No se encontraron artículos con ese criterio', 'Búsqueda sin resultados');
            app.DatosTable.Data = [];
        else
            % Configurar nombres de columnas para la tabla UI
            app.DatosTable.ColumnName = {'Código', 'Nombre', 'Categoría', 'Precio', 'Cantidad', 'Proveedor', 'Fecha'};
            app.DatosTable.Data = data;
            
            message = sprintf('Se encontraron %d artículos', size(data, 1));
            uialert(app.UIFigure, message, 'Resultados de búsqueda', 'Icon', 'success');
        end
        
    catch ME
        errorMsg = sprintf('Error en la búsqueda:\n%s\n\nConsulta SQL:\n%s', ME.message, query);
        uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
        disp(getReport(ME, 'extended'));
    end
        
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
          try
        % 1. Verificar selección
        selectedRow = app.DatosTable.Selection;
        if isempty(selectedRow)
            uialert(app.UIFigure, 'Seleccione un producto para eliminar', 'Advertencia', 'Icon', 'warning');
            return;
        end

        % 2. Obtener datos de la fila seleccionada (manejo seguro para tablas y cell arrays)
        rowData = app.DatosTable.Data(selectedRow(1), :);
        
        % Convertir a string si es necesario
        if iscell(rowData)
            codigoProducto = char(rowData{1}); % Primera columna es código
            nombreProducto = char(rowData{2}); % Segunda columna es nombre
        else
            codigoProducto = string(rowData(1));
            nombreProducto = string(rowData(2));
        end

        % 3. Diálogo de confirmación
        confirmMessage = sprintf('¿Eliminar este producto?\n\nCódigo: %s\nNombre: %s', ...
                               codigoProducto, nombreProducto);
        
        selection = uiconfirm(app.UIFigure, confirmMessage, ...
                            'Confirmar eliminación', ...
                            'Options', {'Eliminar', 'Cancelar'}, ...
                            'DefaultOption', 2, ...
                            'Icon', 'warning');

        % 4. Procesar eliminación
        if strcmp(selection, 'Eliminar')
            % Verificar conexión
            if isempty(app.conn) || ~isvalid(app.conn)
                app.conn = conectarPostgres();
            end

            % Construir consulta DELETE (versión segura)
            deleteQuery = ['DELETE FROM articulos WHERE codigo = ''' ...
                         strrep(char(codigoProducto), '''', '''''') ''''];
            
            % Ejecutar consulta
            exec(app.conn, deleteQuery);
            
            % Confirmar y actualizar
            uialert(app.UIFigure, 'Producto eliminado correctamente', 'Éxito', 'Icon', 'success');
            app.actualizarTabla();
        end

    catch ME
         % Versión CORREGIDA del manejo de errores:
        if exist('deleteQuery', 'var')
            errorMsg = sprintf('Error al eliminar:\n%s\n\nConsulta SQL:\n%s', ...
                             ME.message, deleteQuery);
        else
            errorMsg = sprintf('Error al eliminar:\n%s\n\nConsulta SQL: N/A', ME.message);
        end
        
        uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
        disp(getReport(ME, 'extended'));
    end
        end

        % Button pushed function: RefrescarButton
        function RefrescarButtonPushed(app, event)
            app.conn = app.conn; % conexión PostgreSQL activa
            datos = fetch(app.conn, "SELECT * FROM articulos ORDER BY id");
            app.UITable.Data = datos;
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
           % Verificar si hay datos para exportar
    if isempty(app.DatosTable.Data)
        uialert(app.UIFigure, 'No hay datos para exportar', 'Advertencia', 'Icon', 'warning');
        return;
    end
    
    % Crear diálogo para guardar archivo
    [filename, pathname] = uiputfile(...
        {'*.xlsx', 'Libro de Excel (*.xlsx)'; 
         '*.csv', 'Archivo CSV (*.csv)'}, ...
        'Guardar como');
    
    % Si el usuario no cancela la operación
    if ~isequal(filename, 0)
        % Construir la ruta completa del archivo
        fullpath = fullfile(pathname, filename);
        
        try
            % Obtener los datos de la tabla
            tableData = app.DatosTable.Data;
            
            % Determinar si necesitamos convertir a tabla MATLAB
            if ~istable(tableData) && ~ismatrix(tableData)
                % Convertir cell array a tabla
                columnNames = app.DatosTable.ColumnName;
                if isempty(columnNames)
                    columnNames = arrayfun(@(x) sprintf('Columna%d', x), ...
                                        1:size(tableData, 2), 'UniformOutput', false);
                end
                
                % Crear tabla MATLAB
                exportData = cell2table(tableData, 'VariableNames', columnNames);
            else
                exportData = tableData;
            end
            
            % Escribir el archivo según la extensión
            [~, ~, ext] = fileparts(filename);
            switch lower(ext)
                case '.xlsx'
                    writetable(exportData, fullpath, 'WriteMode', 'overwritesheet', ...
                              'PreserveFormat', true);
                case '.csv'
                    writetable(exportData, fullpath);
                otherwise
                    writetable(exportData, fullpath);
            end
            
            % Mostrar mensaje de éxito
            uialert(app.UIFigure, sprintf('Datos exportados exitosamente a:\n%s', fullpath), ...
                   'Exportación completada', 'Icon', 'success');
            
        catch ME
            % Manejo de errores
            errorMsg = sprintf('Error al exportar:\n%s', ME.message);
            uialert(app.UIFigure, errorMsg, 'Error de exportación', 'Icon', 'error');
        end
    end
        end

        % Button pushed function: VovelButton
        function VovelButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
        '¿Desea volver al Menú?', ...  % Mensaje
        'Confirmar salida', ...                                 % Título
        'Options', {'Sí', 'No'}, ...                            % Botones
        'DefaultOption', 2, ...                                 % Opción por defecto: No
        'CancelOption', 2);  
         if strcmp(selection, 'Sí')
            MainMenuApp;
         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosTable = uitable(app.UIFigure);
            app.DatosTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DatosTable.RowName = {};
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Gestion de Inventario';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.buscarButton = uibutton(app.UIFigure, 'push');
            app.buscarButton.ButtonPushedFcn = createCallbackFcn(app, @buscarButtonPushed, true);
            app.buscarButton.Text = 'buscar';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.RefrescarButton = uibutton(app.UIFigure, 'push');
            app.RefrescarButton.ButtonPushedFcn = createCallbackFcn(app, @RefrescarButtonPushed, true);
            app.RefrescarButton.Text = 'Refrescar';
            app.VovelButton = uibutton(app.UIFigure, 'push');
            app.VovelButton.ButtonPushedFcn = createCallbackFcn(app, @VovelButtonPushed, true);
            app.VovelButton.Text = 'Vovel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatosApp_LISTO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end