classdef GraficosApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GenerarButton  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            conn = conectarPostgres();
    data = fetch(conn, 'SELECT region FROM personas');
    close(conn);

    regiones = data.region;
    [cuentas, nombres] = groupcounts(regiones);

    % Gráfica de barras por región
    bar(app.UIAxes, cuentas);
    app.UIAxes.XTickLabel = nombres;
    app.UIAxes.Title.String = 'Cantidad de personas por región';
    app.UIAxes.XLabel.String = 'Región';
    app.UIAxes.YLabel.String = 'Cantidad';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar Graficas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficosApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end