classdef LoginApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Button               matlab.ui.control.Button
        pruebaButton         matlab.ui.control.Button
        IniciarSesionButton  matlab.ui.control.Button
        ContraseLabel        matlab.ui.control.Label
        UsuarioLabel         matlab.ui.control.Label
        ContrasenaEditField  matlab.ui.control.EditField
        UsuarioEditField     matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarSesionButton
        function IniciarSesionButtonPushed(app, event)
            % Obtener valores ingresados
    usuario = app.UsuarioEditField.Value;
    contrasena = app.ContrasenaEditField.Value;

    % Credenciales fijas (puedes luego conectarlo con PostgreSQL)
    usuarioValido = "Postgres";
    contrasenaValida = "12345";

        if strcmp(usuario, usuarioValido) && strcmp(contrasena, contrasenaValida)
        % Mostrar mensaje de éxito
        uialert(app.UIFigure, 'Inicio de sesión exitoso.', 'Bienvenido');

        % Cerrar login y abrir otra app (menú principal)
        pause(1);  % pequeña pausa
        close(app.UIFigure);  % cerrar ventana de login
        MainMenuApp;  % abrir siguiente ventana (debes crearla también)
    else
        % Mostrar error
        uialert(app.UIFigure, 'Usuario o contraseña incorrectos.', 'Error');
       
        end
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContrasenaEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'Usuario';
            app.ContraseLabel = uilabel(app.UIFigure);
            app.ContraseLabel.Text = 'Contraseña';
            app.IniciarSesionButton = uibutton(app.UIFigure, 'push');
            app.IniciarSesionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarSesionButtonPushed, true);
            app.IniciarSesionButton.Text = 'Iniciar Sesion';
            app.pruebaButton = uibutton(app.UIFigure, 'push');
            app.pruebaButton.Text = 'prueba';
            app.Button = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end