classdef MainMenuApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EntretenimientoButton  matlab.ui.control.Button
        SalirButton            matlab.ui.control.Button
        CalculosButton         matlab.ui.control.Button
        VerDatosButton         matlab.ui.control.Button
        MENUPRINCIPALLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VerDatosButton
        function VerDatosButtonPushed(app, event)
            DatosApp; 
        end

        % Button pushed function: CalculosButton
        function CalculosButtonPushed(app, event)
            CalculosApp;
        end

        % Callback function
        function GraficasButtonPushed(app, event)
            GraficosApp;
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
        '¿Estás seguro de que deseas salir del sistema?', ...  % Mensaje
        'Confirmar salida', ...                                 % Título
        'Options', {'Sí', 'No'}, ...                            % Botones
        'DefaultOption', 2, ...                                 % Opción por defecto: No
        'CancelOption', 2);  
         if strcmp(selection, 'Sí')
        close(app.UIFigure);
    end
        end

        % Button pushed function: EntretenimientoButton
        function EntretenimientoButtonPushed(app, event)
            JuegoTotito;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MENUPRINCIPALLabel = uilabel(app.UIFigure);
            app.MENUPRINCIPALLabel.Text = 'MENU PRINCIPAL';
            app.VerDatosButton = uibutton(app.UIFigure, 'push');
            app.VerDatosButton.ButtonPushedFcn = createCallbackFcn(app, @VerDatosButtonPushed, true);
            app.VerDatosButton.Text = 'Ver Datos';
            app.CalculosButton = uibutton(app.UIFigure, 'push');
            app.CalculosButton.ButtonPushedFcn = createCallbackFcn(app, @CalculosButtonPushed, true);
            app.CalculosButton.Text = 'Calculos';
            app.SalirButton = uibutton(app.UIFigure, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.EntretenimientoButton = uibutton(app.UIFigure, 'push');
            app.EntretenimientoButton.ButtonPushedFcn = createCallbackFcn(app, @EntretenimientoButtonPushed, true);
            app.EntretenimientoButton.Text = 'Entretenimiento';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainMenuApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end